classdef IU_SCARA_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DerivativeSpinner_2         matlab.ui.control.Spinner
        TomadePiezaLabel            matlab.ui.control.Label
        VisualizarVariablesButton   matlab.ui.control.StateButton
        ComputerVisionImageLabel    matlab.ui.control.Label
        ColorDetectionLabel         matlab.ui.control.Label
        ESP32ConnectionLabel        matlab.ui.control.Label
        Angulo2EditField            matlab.ui.control.NumericEditField
        Angulo2EditFieldLabel       matlab.ui.control.Label
        Angulo1EditField            matlab.ui.control.NumericEditField
        Angulo1EditFieldLabel       matlab.ui.control.Label
        PosicionenZEditField        matlab.ui.control.NumericEditField
        PosicionenZEditFieldLabel   matlab.ui.control.Label
        PosicionenYEditField        matlab.ui.control.NumericEditField
        PosicionenYEditFieldLabel   matlab.ui.control.Label
        PosicionenXEditField        matlab.ui.control.NumericEditField
        PosicionenXEditFieldLabel   matlab.ui.control.Label
        PuzzleIntegrationLabel      matlab.ui.control.Label
        ColorDropDownLabel          matlab.ui.control.Label
        ColorDropDown               matlab.ui.control.DropDown
        FiguraDropDown              matlab.ui.control.DropDown
        FiguraDropDownLabel         matlab.ui.control.Label
        VariableVisualizationLabel  matlab.ui.control.Label
        DesconectarButton           matlab.ui.control.StateButton
        PosicinFinalZSpinner        matlab.ui.control.Spinner
        PosicinFinalZSpinnerLabel   matlab.ui.control.Label
        ConectarButton              matlab.ui.control.StateButton
        RicardoBarrnHernndezA01661454Label  matlab.ui.control.Label
        ResolverRompecabezasButton  matlab.ui.control.StateButton
        ConexinUSBLamp              matlab.ui.control.Lamp
        ConexinUSBLampLabel         matlab.ui.control.Label
        nguloJoint2Spinner          matlab.ui.control.Spinner
        nguloJoint2SpinnerLabel     matlab.ui.control.Label
        nguloJoint1Spinner          matlab.ui.control.Spinner
        nguloJoint1SpinnerLabel     matlab.ui.control.Label
        PosicinFinalYSpinner        matlab.ui.control.Spinner
        PosicinFinalYSpinnerLabel   matlab.ui.control.Label
        PosicinFinalXSpinner        matlab.ui.control.Spinner
        PosicinFinalXSpinnerLabel   matlab.ui.control.Label
        DerivativeSpinner           matlab.ui.control.Spinner
        DerivativeSpinnerLabel      matlab.ui.control.Label
        IntegralSpinner             matlab.ui.control.Spinner
        IntegralSpinnerLabel        matlab.ui.control.Label
        ProportionalSpinner         matlab.ui.control.Spinner
        ProportionalSpinnerLabel    matlab.ui.control.Label
        ActualizarVariablesButton   matlab.ui.control.StateButton
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        PIDControllerLabel          matlab.ui.control.Label
        InverseKinematicsLabel      matlab.ui.control.Label
        ForwardKinematicsLabel      matlab.ui.control.Label
        EquipoIIDiseoyDesarrollodeRobotsLabel  matlab.ui.control.Label
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         app.Property = serialport("COM4",115200);
         app.ConexinUSBLamp.Color = "Red";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % fclose(app.Property);
            % delete(app.Property);
            % delete(app) 
        end

        % Value changed function: ConectarButton
        function ConectarButtonValueChanged(app, event)
            global im
            app.ConexinUSBLamp.Color = "Green";
        end

        % Value changed function: DesconectarButton
        function DesconectarButtonValueChanged(app, event)
            app.ConexinUSBLamp.Color ="Red";
            fclose(app.Property);
        end

        % Value changed function: nguloJoint1Spinner
        function nguloJoint1SpinnerValueChanged(app, event)
            global AnguloJoint1
            AnguloJoint1 = app.nguloJoint1Spinner.Value;
        end

        % Value changed function: nguloJoint2Spinner
        function nguloJoint2SpinnerValueChanged(app, event)
            global AnguloJoint2
            AnguloJoint2 = app.nguloJoint2Spinner.Value;
        end

        % Value changed function: PosicinFinalXSpinner
        function PosicinFinalXSpinnerValueChanged(app, event)
            global PosicionX
            PosicionX = app.PosicinFinalXSpinner.Value;
        end

        % Value changed function: PosicinFinalYSpinner
        function PosicinFinalYSpinnerValueChanged(app, event)
            global PosicionY
            PosicionY = app.PosicinFinalYSpinner.Value; 
        end

        % Value changed function: PosicinFinalZSpinner
        function PosicinFinalZSpinnerValueChanged(app, event)
            global PosicionZ
            PosicionZ = app.PosicinFinalZSpinner.Value;
        end

        % Value changed function: ProportionalSpinner
        function ProportionalSpinnerValueChanged(app, event)
           global Proportional
           Proportional = app.ProportionalSpinner.Value;
        end

        % Value changed function: IntegralSpinner
        function IntegralSpinnerValueChanged(app, event)
            global Integral
            Integral = app.IntegralSpinner.Value;
        end

        % Value changed function: DerivativeSpinner
        function DerivativeSpinnerValueChanged(app, event)
            global Derivative
            Derivative = app.DerivativeSpinner.Value;
        end

        % Value changed function: ResolverRompecabezasButton
        function ResolverRompecabezasButtonValueChanged(app, event)
%% Deteccion de lineas
clc
clear all
close all
%%Select a camera
%wl=webcamlist %List of USB webcams
cam = webcam("USB2.0 PC CAMERA") %Select de ewbcam of the list

%%
Img=snapshot(cam)
Img=imsharpen(Img)
imshow(Img)
clear cam;

%Img = imread("figuras_4.jpg")

% Paso 1: Convertir la imagen a espacio de color HSV
imagen_hsv = rgb2hsv(Img);

% Paso 2: Ajustar la saturación
saturacion_factor = 2.0; % Factor de saturación, puedes ajustar este valor según sea necesario
imagen_hsv(:,:,2) = imagen_hsv(:,:,2) * saturacion_factor;

% Paso 3: Convertir la imagen de nuevo a RGB
imagen_saturada = hsv2rgb(imagen_hsv);

% Paso 3: Definir los rangos de valores para cada color en HSV
rojo = [(320/360) (350/360)]; % Rango de tonos para el rojo
verde = [(80/360) (163/360)]; % Rango de tonos para el verde
azul = [(190/360) (290/360)]; % Rango de tonos para el azul
amarillo = [(30/360) (140/360)]; % Rango de tonos para el amarillo

% Paso 4: Crear máscaras para cada color
mascara_rojo = (imagen_hsv(:,:,1) >= rojo(1) & imagen_hsv(:,:,1) <= rojo(2));
mascara_verde = (imagen_hsv(:,:,1) >= verde(1) & imagen_hsv(:,:,1) <= verde(2));
mascara_azul = (imagen_hsv(:,:,1) >= azul(1) & imagen_hsv(:,:,1) <= azul(2));
mascara_amarillo = (imagen_hsv(:,:,1) >= amarillo(1) & imagen_hsv(:,:,1) <= amarillo(2));

Color = input("Cual color:", "s")

% Paso 5: Visualizar los resultados
% figure;
% subplot(2, 2, 1);

if Color == "Rojo"
    I_color = Img.*repmat(uint8(mascara_rojo), [1 1 3]);
% title('Detección de Rojo');
% 
% subplot(2, 2, 2);
elseif Color == "Verde"
    I_color = Img.*repmat(uint8(mascara_verde), [1 1 3]);
% title('Detección de Verde');
% 
% subplot(2, 2, 3);
elseif Color == "Azul"
    I_color = Img.*repmat(uint8(mascara_azul), [1 1 3]);
% title('Detección de Azul');
% 
% subplot(2, 2, 4);
elseif Color == "Amarillo"
    I_color = Img.*repmat(uint8(mascara_amarillo), [1 1 3]);
end

% Convierte la imagen a escala de grises (formato 2D)
I_color = im2double(I_color);

% Muestra la imagen en escala de grises
imshow(I_color);

% title(['Detección de Amarillo']);
% 
% figure;
% imshow(Img);
% title('Imagen Original');
% hold on 

% Convertir la imagen a escala de grises
I_gray = rgb2gray(I_color);

% Aplicar filtro Wiener a cada canal de color por separado
for i = 1:3
    I_color(:,:,i) = wiener2(I_color(:,:,i), [50 50]);
end

% Convertir la imagen a binaria
I_binary = imbinarize(I_gray, 0.40);

% Eliminar ruido
I_binary = bwareaopen(I_binary, 130);

% Rellenar espacios de los objetos
se = strel('disk', 5);
I_binary = imclose(I_binary, se);
imshow(I_binary)
[B,L] = bwboundaries(I_binary, 'noholes');

figure
imshow(Img)

hold on

for k = 1:length(B),boundary = B{k}; % Ciclo para dibujar los contornos de las regiones 
    plot(boundary(:,2),boundary(:,1), 'w', 'LineWidth', 2)
end

% Extraemos la informacion de cada una de las regiones en este caso el Area 
% y el centro
stats = regionprops(L, 'Area', 'Centroid');
threshold = 0.20;
for k = 1:length(B)
    boundary = B{k};
    delta_sq = diff(boundary).^2;
    perimeter = sum(sqrt(sum(delta_sq,2))); %Extraemos perimetro
    area = stats(k).Area; %Extraemos el Area
    metric = 4*pi*area/perimeter^2; %Extraemos la metrica con el area y el perimetro
    if metric > threshold
        centroid = stats(k).Centroid;
        plot(centroid(1), centroid(2), 'ko');
    end
end

% Inicializar una matriz para almacenar los conteos de círculos oscuros
conteoCirculos = zeros(length(B), 1);

% Definimos el rango del radio
Rmin = 20;
Rmax = 28;

for k = 1:length(B)
    boundary = B{k};
    % Crear una máscara binaria para la región actual (por ejemplo, usando poly2mask)
    mask = poly2mask(boundary(:,2), boundary(:,1), size(I_color, 1), size(I_color, 2));
    % Encuentra todos los círculos oscuros de la imagen dentro del rango de radio.
    [DarkCenter, radiiDark] = imfindcircles(I_color, [Rmin Rmax], 'ObjectPolarity', 'dark');
    
    % Filtrar los círculos oscuros que están dentro del contorno actual
    circulosDentroContorno = [];
    for i = 1:numel(radiiDark)
        x = round(DarkCenter(i, 1));
        y = round(DarkCenter(i, 2));
        if mask(y, x) == 1
            circulosDentroContorno = [circulosDentroContorno; DarkCenter(i, :)];
        end
    end
    
    % Etiquetar los círculos según la cantidad de centros
    numCentros = size(circulosDentroContorno, 1);
    if numCentros == 4
        etiqueta = 'Cuadrado';
    elseif numCentros == 3
        etiqueta = 'Triángulo';
    elseif numCentros == 2
        etiqueta = 'Rectángulo';
    else
        etiqueta = 'Círculo';
    end
    
    % Dibujar líneas azules alrededor de los círculos oscuros dentro del contorno
    viscircles(DarkCenter, radiiDark, 'LineStyle', '--');
    
    % Mostrar la etiqueta en la imagen
    text(boundary(1, 2), boundary(1, 1), etiqueta, 'Color', 'white', 'FontSize', 12, 'FontWeight', 'bold');
    
    % Almacenar el número de círculos oscuros encontrados dentro del contorno actual
    conteoCirculos(k) = numCentros;
end

N=[-3, -3, 5; -3, 0, 5; -3, -3, 5];
NW=[-3, 5, 5; -3, 0, 5; -3, -3, -3];
W=[5, 5, 5; -3, 0, -3; -3, -3, -3];
SW=[5, 5, -3; 5, 0, -3; -3, -3, -3];
S=[5, -3, -3; 5, 0, -3; 5, -3, -3];
SE=[-3, -3, -3; 5, 0, -3; 5, 5, -3];
E=[-3, -3, -3; -3, 0, -3; 5, 5, 5];
NE=[-3, -3, -3; -3, 0, 5; -3, 5, 5];

% Convertir la imagen a escala de grises si es necesario
if size(Img, 3) == 3
    Img = rgb2gray(Img);
end

% Aplicar los filtros de Kirsch
n = imfilter(Img, N);
nw = imfilter(Img, NW);
w = imfilter(Img, W);
sw = imfilter(Img, SW);
s = imfilter(Img, S);
se = imfilter(Img, SE);
e = imfilter(Img, E);
ne = imfilter(Img, NE);

% Sumar resultados
Kirsh_sum = n + nw + w + sw + s + se + e + ne;

% Binarizar si es necesario
bin_sum = imbinarize(Kirsh_sum);

% Ajustar valores para visualización
Kirsh_sum(Kirsh_sum > 200) = 255;
Kirsh_sum(Kirsh_sum < 200) = 0;

% Aplicar la transformada de Hough
[H, theta, rho] = hough(Kirsh_sum, 'RhoResolution', 1, 'Theta', -90:0.05:89);
peaks = houghpeaks(H, 30, 'Threshold', 1);
lines = houghlines(Kirsh_sum, theta, rho, peaks, 'FillGap', 5, 'MinLength', 7);

% Filtrar líneas por longitud y ángulo
min_length = 150;
min_angle = -45;
max_angle = 45;

% Mostrar la imagen y las líneas largas
figure, imshow(Img), hold on
for k = 1:length(lines)
    xy = [lines(k).point1; lines(k).point2];
    len = norm(lines(k).point1 - lines(k).point2);
    angle = atan2(diff(xy(:,2)), diff(xy(:,1))) * 180 / pi;

    if len > min_length && angle > min_angle && angle < max_angle
        plot(xy(:,1), xy(:,2), 'LineWidth', 2, 'Color', 'green');
        text(mean(xy(:,1)), mean(xy(:,2)), sprintf('%.2f°', angle), ...
             'Color', 'blue', 'FontSize', 12, 'FontWeight', 'bold');
    end
end
hold off

% Mostrar los resultados (opcional)
%       
disp(conteoCirculos);

% Crear una matriz para almacenar las coordenadas de los centroides
centroids = zeros(numel(stats), 2);
% Almacenar las coordenadas de los centroides en la matriz
for x = 1:numel(stats)
    centroids(x,:) = stats(x).Centroid;
end

figure;
plot(centroids(:,1), centroids(:,2), 'b*')
axis([0 720 0 1280]);

% Convertir las coordenadas de píxeles a centímetros
factor_conversion = 5 / 207; % Cada 5 cm son 207 píxele
coordenadas_cm = centroids * factor_conversion;

% Mostrar las coordenadas en centímetros
disp('Coordenadas en Centímetros:');
disp(coordenadas_cm);

% Guardar las coordenadas en una matriz (si deseas)
matriz_coordenadas = coordenadas_cm;
        
        end

        % Value changed function: ActualizarVariablesButton
        function ActualizarVariablesButtonValueChanged(app, event)
            global PosicionX PosicionY PosicionZ AnguloJoint1 AnguloJoint2 P I D Pieza

            PosicionX = app.PosicinFinalXSpinner.Value;
            PosicionY = app.PosicinFinalYSpinner.Value;
            PosicionZ = app.PosicinFinalZSpinner.Value;

            AnguloJoint1 = app.nguloJoint1Spinner.Value;
            AnguloJoint2 = app.nguloJoint2Spinner.Value;

            P = app.ProportionalSpinner.Value;
            I = app.IntegralSpinner. Value;
            D = app.DerivativeSpinner.Value;

            Pieza = app.DerivativeSpinner_2.Value;

            DataToSend = sprintf('%f,%f,%f,%f,%f,%f,%f,%f,%f,%f \n',AnguloJoint1,AnguloJoint2,PosicionX,PosicionY,PosicionZ,P,I,D,Pieza)

            write(app.Property,DataToSend,"string");
            
        end

        % Value changed function: Angulo1EditField
        function Angulo1EditFieldValueChanged(app, event)
            global Theta1_Visualizar
            Theta1_Visualizar = app.Angulo1EditField.Value;
        end

        % Value changed function: Angulo2EditField
        function Angulo2EditFieldValueChanged(app, event)
            global Theta2_Visualizar
            Theta2_Visualizar = app.Angulo2EditField.Value;
        end

        % Value changed function: PosicionenXEditField
        function PosicionenXEditFieldValueChanged(app, event)
            global PosicionX_Visualizar
            PosicionX_Visualizar = app.PosicionenXEditField.Value;
        end

        % Value changed function: VisualizarVariablesButton
        function VisualizarVariablesButtonValueChanged(app, event)
            DataToRead2 = read(app.Property,70,"string");
          
            [A, n] = sscanf(DataToRead2,"%f,%f,%f,%f,%f\n")

            A(1) = app.PosicionenXEditField.Value;
            A(2) = app.PosicionenYEditField.Value;
            A(3) = app.PosicionenZEditField.Value;
            A(4) = app.Angulo1EditField.Value;
            A(5) = app.Angulo2EditField.Value;

        end

        % Value changed function: PosicionenYEditField
        function PosicionenYEditFieldValueChanged(app, event)
            global PosicionY_Visualizar
            PosicionY_Visualizar = app.PosicionenYEditField.Value;
        end

        % Value changed function: PosicionenZEditField
        function PosicionenZEditFieldValueChanged(app, event)
            global PosicionZ_Visualizar
            PosicionZ_Visualizar = app.PosicionenZEditField.Value;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XLim = [0 720];
            app.UIAxes_2.YLim = [0 1080];
            app.EquipoIIDiseoyDesarrollodeRobotsLabel = uilabel(app.UIFigure);
            app.EquipoIIDiseoyDesarrollodeRobotsLabel.Text = 'Equipo II - Diseño y Desarrollo de Robots';
            app.ForwardKinematicsLabel = uilabel(app.UIFigure);
            app.ForwardKinematicsLabel.Text = 'Forward Kinematics';
            app.InverseKinematicsLabel = uilabel(app.UIFigure);
            app.InverseKinematicsLabel.Text = 'Inverse Kinematics';
            app.PIDControllerLabel = uilabel(app.UIFigure);
            app.PIDControllerLabel.Text = 'PID Controller';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pedro-raccoon-raccoon.gif');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'pedro-raccoon-raccoon.gif');
            app.ActualizarVariablesButton = uibutton(app.UIFigure, 'state');
            app.ActualizarVariablesButton.ValueChangedFcn = createCallbackFcn(app, @ActualizarVariablesButtonValueChanged, true);
            app.ActualizarVariablesButton.Text = 'Actualizar Variables';
            app.ProportionalSpinnerLabel = uilabel(app.UIFigure);
            app.ProportionalSpinnerLabel.Text = 'Proportional';
            app.ProportionalSpinner = uispinner(app.UIFigure);
            app.ProportionalSpinner.ValueChangedFcn = createCallbackFcn(app, @ProportionalSpinnerValueChanged, true);
            app.IntegralSpinnerLabel = uilabel(app.UIFigure);
            app.IntegralSpinnerLabel.Text = 'Integral';
            app.IntegralSpinner = uispinner(app.UIFigure);
            app.IntegralSpinner.ValueChangedFcn = createCallbackFcn(app, @IntegralSpinnerValueChanged, true);
            app.DerivativeSpinnerLabel = uilabel(app.UIFigure);
            app.DerivativeSpinnerLabel.Text = 'Derivative';
            app.DerivativeSpinner = uispinner(app.UIFigure);
            app.DerivativeSpinner.ValueChangedFcn = createCallbackFcn(app, @DerivativeSpinnerValueChanged, true);
            app.PosicinFinalXSpinnerLabel = uilabel(app.UIFigure);
            app.PosicinFinalXSpinnerLabel.Text = 'Posición Final X';
            app.PosicinFinalXSpinner = uispinner(app.UIFigure);
            app.PosicinFinalXSpinner.ValueChangedFcn = createCallbackFcn(app, @PosicinFinalXSpinnerValueChanged, true);
            app.PosicinFinalYSpinnerLabel = uilabel(app.UIFigure);
            app.PosicinFinalYSpinnerLabel.Text = 'Posición Final Y';
            app.PosicinFinalYSpinner = uispinner(app.UIFigure);
            app.PosicinFinalYSpinner.ValueChangedFcn = createCallbackFcn(app, @PosicinFinalYSpinnerValueChanged, true);
            app.nguloJoint1SpinnerLabel = uilabel(app.UIFigure);
            app.nguloJoint1SpinnerLabel.Text = 'Ángulo Joint 1';
            app.nguloJoint1Spinner = uispinner(app.UIFigure);
            app.nguloJoint1Spinner.ValueChangedFcn = createCallbackFcn(app, @nguloJoint1SpinnerValueChanged, true);
            app.nguloJoint2SpinnerLabel = uilabel(app.UIFigure);
            app.nguloJoint2SpinnerLabel.Text = 'Ángulo Joint 2';
            app.nguloJoint2Spinner = uispinner(app.UIFigure);
            app.nguloJoint2Spinner.ValueChangedFcn = createCallbackFcn(app, @nguloJoint2SpinnerValueChanged, true);
            app.ConexinUSBLampLabel = uilabel(app.UIFigure);
            app.ConexinUSBLampLabel.Text = 'Conexión USB';
            app.ConexinUSBLamp = uilamp(app.UIFigure);
            app.ResolverRompecabezasButton = uibutton(app.UIFigure, 'state');
            app.ResolverRompecabezasButton.ValueChangedFcn = createCallbackFcn(app, @ResolverRompecabezasButtonValueChanged, true);
            app.ResolverRompecabezasButton.Text = 'Resolver Rompecabezas';
            app.RicardoBarrnHernndezA01661454Label = uilabel(app.UIFigure);
            app.RicardoBarrnHernndezA01661454Label.Text = 'Ricardo Barrón Hernández - A01661454 Sebastian Ramirez García - A01656569 Emiliano Barreto García - A01662632';
            app.ConectarButton = uibutton(app.UIFigure, 'state');
            app.ConectarButton.ValueChangedFcn = createCallbackFcn(app, @ConectarButtonValueChanged, true);
            app.ConectarButton.Text = 'Conectar';
            app.PosicinFinalZSpinnerLabel = uilabel(app.UIFigure);
            app.PosicinFinalZSpinnerLabel.Text = 'Posición Final Z';
            app.PosicinFinalZSpinner = uispinner(app.UIFigure);
            app.PosicinFinalZSpinner.ValueChangedFcn = createCallbackFcn(app, @PosicinFinalZSpinnerValueChanged, true);
            app.DesconectarButton = uibutton(app.UIFigure, 'state');
            app.DesconectarButton.ValueChangedFcn = createCallbackFcn(app, @DesconectarButtonValueChanged, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.VariableVisualizationLabel = uilabel(app.UIFigure);
            app.VariableVisualizationLabel.Text = 'Variable Visualization';
            app.FiguraDropDownLabel = uilabel(app.UIFigure);
            app.FiguraDropDownLabel.Text = 'Figura';
            app.FiguraDropDown = uidropdown(app.UIFigure);
            app.FiguraDropDown.Items = {'Cuadrado', 'Circulo', 'Rectangulo', 'Triangulo'};
            app.FiguraDropDown.Value = 'Cuadrado';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'Rojo', 'Azul', 'Verde', 'Amarillo'};
            app.ColorDropDown.Value = 'Rojo';
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.PuzzleIntegrationLabel = uilabel(app.UIFigure);
            app.PuzzleIntegrationLabel.Text = 'Puzzle Integration';
            app.PosicionenXEditFieldLabel = uilabel(app.UIFigure);
            app.PosicionenXEditFieldLabel.Text = 'Posicion en X';
            app.PosicionenXEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosicionenXEditField.ValueChangedFcn = createCallbackFcn(app, @PosicionenXEditFieldValueChanged, true);
            app.PosicionenYEditFieldLabel = uilabel(app.UIFigure);
            app.PosicionenYEditFieldLabel.Text = 'Posicion en Y';
            app.PosicionenYEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosicionenYEditField.ValueChangedFcn = createCallbackFcn(app, @PosicionenYEditFieldValueChanged, true);
            app.PosicionenZEditFieldLabel = uilabel(app.UIFigure);
            app.PosicionenZEditFieldLabel.Text = 'Posicion en Z';
            app.PosicionenZEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosicionenZEditField.ValueChangedFcn = createCallbackFcn(app, @PosicionenZEditFieldValueChanged, true);
            app.Angulo1EditFieldLabel = uilabel(app.UIFigure);
            app.Angulo1EditFieldLabel.Text = 'Angulo θ1';
            app.Angulo1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Angulo1EditField.ValueChangedFcn = createCallbackFcn(app, @Angulo1EditFieldValueChanged, true);
            app.Angulo2EditFieldLabel = uilabel(app.UIFigure);
            app.Angulo2EditFieldLabel.Text = 'Angulo θ2';
            app.Angulo2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Angulo2EditField.ValueChangedFcn = createCallbackFcn(app, @Angulo2EditFieldValueChanged, true);
            app.ESP32ConnectionLabel = uilabel(app.UIFigure);
            app.ESP32ConnectionLabel.Text = 'ESP-32 Connection';
            app.ColorDetectionLabel = uilabel(app.UIFigure);
            app.ColorDetectionLabel.Text = 'Color Detection';
            app.ComputerVisionImageLabel = uilabel(app.UIFigure);
            app.ComputerVisionImageLabel.Text = 'Computer Vision Image';
            app.VisualizarVariablesButton = uibutton(app.UIFigure, 'state');
            app.VisualizarVariablesButton.ValueChangedFcn = createCallbackFcn(app, @VisualizarVariablesButtonValueChanged, true);
            app.VisualizarVariablesButton.Text = 'Visualizar Variables';
            app.TomadePiezaLabel = uilabel(app.UIFigure);
            app.TomadePiezaLabel.Text = 'Toma de Pieza';
            app.DerivativeSpinner_2 = uispinner(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IU_SCARA_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end